classdef appRLCnt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IsLabel                    matlab.ui.control.Label
        VsLabel                    matlab.ui.control.Label
        IsEditField                matlab.ui.control.NumericEditField
        SliderIs                   matlab.ui.control.Slider
        Imagess                    matlab.ui.control.Image
        Imagent                    matlab.ui.control.Image
        Chonmach                   matlab.ui.control.ListBox
        ChnmchListBoxLabel         matlab.ui.control.Label
        ILtLabel                   matlab.ui.control.Label
        VctLabel                   matlab.ui.control.Label
        PhntchButton               matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        NearcriticallydampedLabel  matlab.ui.control.Label
        SliderIL0                  matlab.ui.control.Slider
        IL0EditField               matlab.ui.control.NumericEditField
        Il0EditFieldLabel          matlab.ui.control.Label
        SliderVc0                  matlab.ui.control.Slider
        Vc0EditField               matlab.ui.control.NumericEditField
        Vc0EditFieldLabel          matlab.ui.control.Label
        VsEditField                matlab.ui.control.NumericEditField
        SliderVs                   matlab.ui.control.Slider
        CEditField                 matlab.ui.control.NumericEditField
        CEditFieldLabel            matlab.ui.control.Label
        SliderC                    matlab.ui.control.Slider
        LEditField                 matlab.ui.control.NumericEditField
        LEditFieldLabel            matlab.ui.control.Label
        SliderL                    matlab.ui.control.Slider
        REditField                 matlab.ui.control.NumericEditField
        REditFieldLabel            matlab.ui.control.Label
        SliderR                    matlab.ui.control.Slider
        UIAxesVc                   matlab.ui.control.UIAxes
        UIAxesIL                   matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function turnonlamp(app,R,L,C)
            delta = (R/L)^2-4/L/C;
            if abs(delta) < 0.1
                app.Lamp.Color = 'red';
            else
                app.Lamp.Color = 'black';
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Imagent.Visible = 'off';
            app.Imagess.Visible = 'off';
            app.IsEditField.Visible = 'off';
            app.SliderIs.Visible = 'off';
            app.VsLabel.Visible = 'off';
            app.IsLabel.Visible = 'off';
            app.VsEditField.Visible = 'off';
            app.SliderVs.Visible = 'off';
        end

        % Value changing function: SliderR
        function SliderRValueChanging(app, event)
            changingValue = event.Value;
            app.REditField.Value = changingValue;
            turnonlamp(app,app.REditField.Value,app.LEditField.Value,app.CEditField.Value);
        end

        % Value changed function: REditField
        function REditFieldValueChanged(app, event)
            value = app.REditField.Value;
            app.SliderR.Value = value;
            turnonlamp(app,app.REditField.Value,app.LEditField.Value,app.CEditField.Value);
        end

        % Button pushed function: PhntchButton
        function PhntchButtonPushed(app, event)
            R = app.REditField.Value;
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            Vs = app.VsEditField.Value;
            Is = app.IsEditField.Value;
            Vc0 = app.Vc0EditField.Value;
            IL0 = app.IL0EditField.Value;

            if app.Chonmach.Value == "RLC noi tiep"
                a = 1; b = R/L; c = 1/L/C; delta = b^2-4*a*c;
                if delta > 0
                    s1 = (-b-sqrt(delta))/2/a;
                    s2 = (-b+sqrt(delta))/2/a;
                    K1 = (IL0-C*(Vc0-Vs)*s2)/(C*(s1-s2));
                    K2 = Vc0 - Vs - K1;

                    t = linspace(0,(-10/s2),200);
                    Vct = Vs + K1*exp(s1*t) + K2*exp(s2*t);
                    ILt = C*(K1*s1*exp(s1*t) + K2*s2*exp(s2*t));
                    plot(app.UIAxesVc, t, Vct, 'b-');
                    plot(app.UIAxesIL, t, ILt, 'b-');
                    grid(app.UIAxesVc,'on');
                    grid(app.UIAxesIL,'on');
                elseif delta == 0
                    s = -b/2/a;
                    K1 = Vc0 - Vs;
                    K2 = (IL0 - C*(Vc0 - Vs)*s)/C;
                    t = 0:(-10/200/s):(-10/s);
                    Vct = Vs+(K1+K2*t).*exp(s*t);
                    ILt = C*((K1+K2*t).*s.*exp(s*t)+K2*exp(s*t));
                    plot(app.UIAxesVc, t, Vct, 'b-');
                    plot(app.UIAxesIL, t, ILt, 'b-');
                    grid(app.UIAxesIL,'on');
                    grid(app.UIAxesVc,'on');
                elseif delta < 0
                    w0 = sqrt(c/a);
                    zeta = b/a/2/w0;
                    sigma = zeta*w0;
                    wd = w0*sqrt(1-zeta^2);
                    K1 = Vc0 - Vs;
                    K2 = (IL0 + C*sigma*K1)/C/wd;

                    t = linspace(0,10/sigma,200);
                    Vct = Vs+ exp(-sigma*t).*(K1*cos(wd*t)+K2*sin(wd*t));
                    ILt = C*(-sigma*exp(-sigma*t).*(K1*cos(wd*t)+K2*sin(wd*t))+...
                        exp(-sigma*t).*(-wd*K1*sin(wd*t)+wd*K2*cos(wd*t)));
                    plot(app.UIAxesVc, t, Vct, 'b-');
                    plot(app.UIAxesIL, t, ILt, 'b-');
                    grid(app.UIAxesIL,'on');
                    grid(app.UIAxesVc,'on');
                end
            else
                a = 1; b = 1/R/C; c = 1/L/C; delta = b^2-4*a*c;
                if delta > 0
                    s1 = (-b-sqrt(delta))/2/a;
                    s2 = (-b+sqrt(delta))/2/a;
                    K1 = (VC0-L*(IL0-Is)*s2)/(L*(s1-s2));
                    K2 = IL0 - Is - K1;

                    t = linspace(0,(-10/s2),200);
                    ILt = Is + K1*exp(s1*t) + K2*exp(s2*t);
                    Vct = L*(K1*s1*exp(s1*t) + K2*s2*exp(s2*t));
                    plot(app.UIAxesVc, t, Vct, 'b-');
                    plot(app.UIAxesIL, t, ILt, 'b-');
                    grid(app.UIAxesVc,'on');
                    grid(app.UIAxesIL,'on');
                elseif delta == 0
                    s = -b/2/a;
                    K1 = IL0 - Is;
                    K2 = (Vc0 - L*(IL0 - Is)*s)/L;
                    t = 0:(-10/200/s):(-10/s);
                    ILt = Is+(K1+K2*t).*exp(s*t);
                    Vct = L*((K1+K2*t).*s.*exp(s*t)+K2*exp(s*t));
                    plot(app.UIAxesVc, t, Vct, 'b-');
                    plot(app.UIAxesIL, t, ILt, 'b-');
                    grid(app.UIAxesIL,'on');
                    grid(app.UIAxesVc,'on');
                elseif delta < 0
                    w0 = sqrt(c/a);
                    zeta = b/a/2/w0;
                    sigma = zeta*w0;
                    wd = w0*sqrt(1-zeta^2);
                    K1 = IL0 - Is;
                    K2 = (Vc0 + L*sigma*K1)/L/wd;

                    t = linspace(0,10/sigma,200);
                    ILt = Is+ exp(-sigma*t).*(K1*cos(wd*t)+K2*sin(wd*t));
                    Vct = L*(-sigma*exp(-sigma*t).*(K1*cos(wd*t)+K2*sin(wd*t))+...
                        exp(-sigma*t).*(-wd*K1*sin(wd*t)+wd*K2*cos(wd*t)));
                    plot(app.UIAxesVc, t, Vct, 'b-');
                    plot(app.UIAxesIL, t, ILt, 'b-');
                    grid(app.UIAxesIL,'on');
                    grid(app.UIAxesVc,'on');
                end
            end

        end

        % Value changing function: SliderL
        function SliderLValueChanging(app, event)
            changingValue = event.Value;
            app.LEditField.Value = changingValue;
            turnonlamp(app,app.REditField.Value,app.LEditField.Value,app.CEditField.Value);
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value = app.LEditField.Value;
            app.SliderL.Value = value;
            turnonlamp(app,app.REditField.Value,app.LEditField.Value,app.CEditField.Value);
        end

        % Value changing function: SliderC
        function SliderCValueChanging(app, event)
            changingValue = event.Value;
            app.CEditField.Value = changingValue;
            turnonlamp(app,app.REditField.Value,app.LEditField.Value,app.CEditField.Value);

        end

        % Value changed function: CEditField
        function CEditFieldValueChanged(app, event)
            value = app.CEditField.Value;
            app.SliderC.Value = value;
            turnonlamp(app,app.REditField.Value,app.LEditField.Value,app.CEditField.Value);

        end

        % Callback function: IL0EditField, SliderIL0, SliderVc0, SliderVs, 
        % ...and 2 other components
        function SliderVsValueChanging(app, event)
            source = event.Source;
            if source == app.SliderVs
                app.VsEditField.Value = app.SliderVs.Value;
            elseif source == app.SliderVc0
                app.Vc0EditField.Value = app.SliderVc0.Value;
            elseif source == app.SliderIL0
                app.IL0EditField.Value = app.SliderIL0.Value;
            elseif source == app.VsEditField
                app.SliderVs.Value = app.VsEditField.Value;
            elseif source == app.Vc0EditField
                app.SliderVc0.Value = app.Vc0EditField.Value;
            elseif source == app.IL0EditField
                app.SliderIL0.Value = app.IL0EditField.Value;
            end
        end

        % Value changed function: Chonmach
        function ChonmachValueChanged(app, event)
            value = app.Chonmach.Value;
            if value == "RLC noi tiep"
                app.Imagent.Visible = 'on';
                app.Imagess.Visible = 'off';
                app.IsEditField.Visible = 'off';
                app.SliderIs.Visible = 'off';
                app.VsEditField.Visible = 'on';
                app.SliderVs.Visible = 'on';
                app.VsLabel.Visible = 'on';
                app.IsLabel.Visible = 'off';
            else
                app.Imagent.Visible = 'off';
                app.Imagess.Visible = 'on';
                app.IsEditField.Visible = 'on';
                app.SliderIs.Visible = 'on';
                app.VsEditField.Visible = 'off';
                app.SliderVs.Visible = 'off';
                app.VsLabel.Visible = 'off';
                app.IsLabel.Visible = 'on';
            end
        end

        % Value changing function: SliderIs
        function SliderIsValueChanging(app, event)
            changingValue = event.Value;
            app.IsEditField.Value = changingValue;
        end

        % Value changed function: IsEditField
        function IsEditFieldValueChanged(app, event)
            value = app.IsEditField.Value;
            app.SliderIs.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesIL = uiaxes(app.UIFigure);
            title(app.UIAxesIL, 'IL(t)')
            xlabel(app.UIAxesIL, 't')
            ylabel(app.UIAxesIL, 'Y')
            zlabel(app.UIAxesIL, 'Z')
            app.UIAxesVc = uiaxes(app.UIFigure);
            title(app.UIAxesVc, 'Vc(t)')
            xlabel(app.UIAxesVc, 't')
            ylabel(app.UIAxesVc, 'Y')
            zlabel(app.UIAxesVc, 'Z')
            app.SliderR = uislider(app.UIFigure);
            app.SliderR.ValueChangingFcn = createCallbackFcn(app, @SliderRValueChanging, true);
            app.SliderR.Value = 50;
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @REditFieldValueChanged, true);
            app.REditField.Value = 50;
            app.SliderL = uislider(app.UIFigure);
            app.SliderL.ValueChangingFcn = createCallbackFcn(app, @SliderLValueChanging, true);
            app.SliderL.Value = 50;
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.LEditField.Value = 50;
            app.SliderC = uislider(app.UIFigure);
            app.SliderC.ValueChangingFcn = createCallbackFcn(app, @SliderCValueChanging, true);
            app.SliderC.Value = 50;
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.UIFigure, 'numeric');
            app.CEditField.ValueChangedFcn = createCallbackFcn(app, @CEditFieldValueChanged, true);
            app.CEditField.Value = 50;
            app.SliderVs = uislider(app.UIFigure);
            app.SliderVs.ValueChangingFcn = createCallbackFcn(app, @SliderVsValueChanging, true);
            app.SliderVs.Value = 50;
            app.VsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VsEditField.ValueChangedFcn = createCallbackFcn(app, @SliderVsValueChanging, true);
            app.VsEditField.Value = 50;
            app.Vc0EditFieldLabel = uilabel(app.UIFigure);
            app.Vc0EditFieldLabel.Text = 'Vc0';
            app.Vc0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Vc0EditField.ValueChangedFcn = createCallbackFcn(app, @SliderVsValueChanging, true);
            app.Vc0EditField.Value = 50;
            app.SliderVc0 = uislider(app.UIFigure);
            app.SliderVc0.ValueChangingFcn = createCallbackFcn(app, @SliderVsValueChanging, true);
            app.SliderVc0.Value = 50;
            app.Il0EditFieldLabel = uilabel(app.UIFigure);
            app.Il0EditFieldLabel.Text = 'Il0';
            app.IL0EditField = uieditfield(app.UIFigure, 'numeric');
            app.IL0EditField.ValueChangedFcn = createCallbackFcn(app, @SliderVsValueChanging, true);
            app.IL0EditField.Value = 50;
            app.SliderIL0 = uislider(app.UIFigure);
            app.SliderIL0.ValueChangingFcn = createCallbackFcn(app, @SliderVsValueChanging, true);
            app.SliderIL0.Value = 50;
            app.NearcriticallydampedLabel = uilabel(app.UIFigure);
            app.NearcriticallydampedLabel.Text = {'Near critically'; 'damped'};
            app.Lamp = uilamp(app.UIFigure);
            app.PhntchButton = uibutton(app.UIFigure, 'push');
            app.PhntchButton.ButtonPushedFcn = createCallbackFcn(app, @PhntchButtonPushed, true);
            app.PhntchButton.Text = 'Phân tích';
            app.VctLabel = uilabel(app.UIFigure);
            app.VctLabel.Text = 'Vc(t) = ';
            app.ILtLabel = uilabel(app.UIFigure);
            app.ILtLabel.Text = 'IL(t) = ';
            app.ChnmchListBoxLabel = uilabel(app.UIFigure);
            app.ChnmchListBoxLabel.Text = 'Chọn mạch';
            app.Chonmach = uilistbox(app.UIFigure);
            app.Chonmach.Items = {'RLC noi tiep', 'RLC song song'};
            app.Chonmach.ValueChangedFcn = createCallbackFcn(app, @ChonmachValueChanged, true);
            app.Chonmach.Value = 'RLC noi tiep';
            app.Imagent = uiimage(app.UIFigure);
            app.Imagent.ImageSource = fullfile(pathToMLAPP, 'noitiep.png');
            app.Imagess = uiimage(app.UIFigure);
            app.Imagess.ImageSource = fullfile(pathToMLAPP, 'songsong.png');
            app.SliderIs = uislider(app.UIFigure);
            app.SliderIs.ValueChangingFcn = createCallbackFcn(app, @SliderIsValueChanging, true);
            app.SliderIs.Value = 50;
            app.IsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IsEditField.ValueChangedFcn = createCallbackFcn(app, @IsEditFieldValueChanged, true);
            app.IsEditField.Value = 50;
            app.VsLabel = uilabel(app.UIFigure);
            app.VsLabel.Text = 'Vs';
            app.IsLabel = uilabel(app.UIFigure);
            app.IsLabel.Text = 'Is';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appRLCnt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end